classdef flexx_point_cloud_stitch_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        NavigationTrustLabel     matlab.ui.control.Label
        nav_trust_slider         matlab.ui.control.Slider
        ProcessButton            matlab.ui.control.Button
        ViewButton               matlab.ui.control.Button
        status_msg               matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        BrowserrffileButton      matlab.ui.control.Button
        BrowsenavdatafileButton  matlab.ui.control.Button
        rrf_path_label           matlab.ui.control.Label
        txt_path_label           matlab.ui.control.Label
        rEditFieldLabel          matlab.ui.control.Label
        rEditField               matlab.ui.control.NumericEditField
        gEditFieldLabel          matlab.ui.control.Label
        gEditField               matlab.ui.control.NumericEditField
        bEditFieldLabel          matlab.ui.control.Label
        bEditField               matlab.ui.control.NumericEditField
        ColorButtonGroup         matlab.ui.container.ButtonGroup
        RedButton                matlab.ui.control.RadioButton
        GreenButton              matlab.ui.control.RadioButton
        BlueButton               matlab.ui.control.RadioButton
        CustomButton             matlab.ui.control.RadioButton
        KillButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EditField.Value = app.nav_trust_slider.Value;
            global nav_file_name pico_file_name
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            global nav_file_name pico_file_name
            app.status_msg.Text = 'Filtering navigation data...';
            calibration_period = 2; % sec
            [time , d_pos, d_vel, d_att, sensor_pos, sensor_att] = comp_filt_fun(nav_file_name,calibration_period);
            
            
            global ptCloudScene
            nav_data_trust = app.nav_trust_slider.Value/100;
            app.status_msg.Text = 'Status: Filtering, synchronizing, rendering clouds, stitching...';
            pause(0.0001)
            ptCloudScene = process_scene(pico_file_name, time, sensor_pos, sensor_att,nav_data_trust);
            app.status_msg.Text = 'Status: Ready';
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            global ptCloudScene
            h = figure();
            pointscolor=uint8(zeros(ptCloudScene.Count,3));
            pointscolor(:,1)=app.rEditField.Value;
            pointscolor(:,2)=app.gEditField.Value;
            pointscolor(:,3)=app.bEditField.Value;  
            ptCloudScene.Color = pointscolor; 
            ptCloudScene.Intensity = [];
            
            pcshow(ptCloudScene,'VerticalAxis','Y', 'VerticalAxisDir', 'Down')
            set(h, 'color', 'k')
            set(gca, 'color', 'k'); 
            set(gca, 'xcolor', 'b'); set(gca, 'ycolor', 'b')
            set(gca, 'zcolor', 'b');
            view([0 -90])
            axis vis3d
        end

        % Value changed function: nav_trust_slider
        function nav_trust_sliderValueChanged(app, event)
            value = app.nav_trust_slider.Value;
            app.EditField.Value = value; 
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.nav_trust_slider.Value = value;
        end

        % Button pushed function: BrowserrffileButton
        function BrowserrffileButtonPushed(app, event)
            global pico_file_name
            [file,path] = uigetfile('*.rrf');
            if isequal(file,0)
               disp('User selected Cancel');
               app.rrf_path_label.Text = 'No file selected.';
            else
               disp(['User selected ', fullfile(path,file)]);
               app.rrf_path_label.Text = file;
               pico_file_name = file; 
            end
        end

        % Button pushed function: BrowsenavdatafileButton
        function BrowsenavdatafileButtonPushed(app, event)
            global nav_file_name
            [file,path] = uigetfile('*.txt');
            if isequal(file,0)
               disp('User selected Cancel');
               app.rrf_path_label.Text = 'No file selected.';
            else
               disp(['User selected ', fullfile(path,file)]);
               app.txt_path_label.Text = file;
               nav_file_name = file; 
            end
        end

        % Selection changed function: ColorButtonGroup
        function ColorButtonGroupSelectionChanged(app, event)
            selectedButton = app.ColorButtonGroup.SelectedObject;
            if app.RedButton.Value == 1
                app.rEditField.Value = 255;
                app.gEditField.Value = 0;
                app.bEditField.Value = 0;
                
            elseif app.GreenButton.Value ==1 
                app.rEditField.Value = 0;
                app.gEditField.Value = 255;
                app.bEditField.Value = 0;
                
            elseif app.BlueButton.Value ==1
                app.rEditField.Value = 0;
                app.gEditField.Value = 0;
                app.bEditField.Value = 255;
                

            elseif app.ColorButtonGroup ==1

                
            end
        end

        % Button pushed function: KillButton
        function KillButtonPushed(app, event)
            quit force
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NavigationTrustLabel = uilabel(app.UIFigure);
            app.NavigationTrustLabel.Text = 'Navigation Trust %';
            app.nav_trust_slider = uislider(app.UIFigure);
            app.nav_trust_slider.ValueChangedFcn = createCallbackFcn(app, @nav_trust_sliderValueChanged, true);
            app.nav_trust_slider.Value = 90;
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.ViewButton = uibutton(app.UIFigure, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.status_msg = uilabel(app.UIFigure);
            app.status_msg.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 90;
            app.BrowserrffileButton = uibutton(app.UIFigure, 'push');
            app.BrowserrffileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowserrffileButtonPushed, true);
            app.BrowserrffileButton.Text = 'Browse rrf file';
            app.BrowsenavdatafileButton = uibutton(app.UIFigure, 'push');
            app.BrowsenavdatafileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsenavdatafileButtonPushed, true);
            app.BrowsenavdatafileButton.Text = 'Browse nav data file';
            app.rrf_path_label = uilabel(app.UIFigure);
            app.rrf_path_label.Text = '';
            app.txt_path_label = uilabel(app.UIFigure);
            app.txt_path_label.Text = '';
            app.rEditFieldLabel = uilabel(app.UIFigure);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.UIFigure, 'numeric');
            app.gEditFieldLabel = uilabel(app.UIFigure);
            app.gEditFieldLabel.Text = 'g';
            app.gEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.Value = 255;
            app.ColorButtonGroup = uibuttongroup(app.UIFigure);
            app.ColorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorButtonGroupSelectionChanged, true);
            app.ColorButtonGroup.Title = 'Color';
            app.RedButton = uiradiobutton(app.ColorButtonGroup);
            app.RedButton.Text = 'Red';
            app.GreenButton = uiradiobutton(app.ColorButtonGroup);
            app.GreenButton.Text = 'Green';
            app.BlueButton = uiradiobutton(app.ColorButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.BlueButton.Value = true;
            app.CustomButton = uiradiobutton(app.ColorButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.KillButton = uibutton(app.UIFigure, 'push');
            app.KillButton.ButtonPushedFcn = createCallbackFcn(app, @KillButtonPushed, true);
            app.KillButton.Text = 'Kill';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = flexx_point_cloud_stitch_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end